classdef BatteryAnalytic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BatteryAnalyticUIFigure        matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        OpenfilesMenu                  matlab.ui.container.Menu
        BasytecfilesMenu               matlab.ui.container.Menu
        NovonixfilesMenu               matlab.ui.container.Menu
        ArbinfilesMenu                 matlab.ui.container.Menu
        DatabasefilesMenu              matlab.ui.container.Menu
        LoaddatafileMenu               matlab.ui.container.Menu
        SavedatafileMenu               matlab.ui.container.Menu
        SaveplotMenu                   matlab.ui.container.Menu
        OpenplotinMATLABfigureMenu     matlab.ui.container.Menu
        AnalysisMenu                   matlab.ui.container.Menu
        DVAMenu                        matlab.ui.container.Menu
        UsingDischargeSequenceMenu     matlab.ui.container.Menu
        UsingChargeSequenceMenu        matlab.ui.container.Menu
        CapacityretentionMenu          matlab.ui.container.Menu
        FromCheckupdataMenu            matlab.ui.container.Menu
        DischargeCapacityMenu          matlab.ui.container.Menu
        ResistanceMenu                 matlab.ui.container.Menu
        PowerMenu                      matlab.ui.container.Menu
        SOCvsCapacityMenu              matlab.ui.container.Menu
        FromCycledataMenu              matlab.ui.container.Menu
        ContinousMenu                  matlab.ui.container.Menu
        DischargeCapacityMenu_2        matlab.ui.container.Menu
        ResistanceMenu_2               matlab.ui.container.Menu
        PowerMenu_2                    matlab.ui.container.Menu
        FECMenu                        matlab.ui.container.Menu
        DischargecapacityMenu          matlab.ui.container.Menu
        ResistanceMenu_3               matlab.ui.container.Menu
        PowerMenu_3                    matlab.ui.container.Menu
        ICAMenu                        matlab.ui.container.Menu
        UsingDischargeSequenceMenu_2   matlab.ui.container.Menu
        UsingChargeSequenceMenu_2      matlab.ui.container.Menu
        PlotsMenu_2                    matlab.ui.container.Menu
        PlotsMenu                      matlab.ui.container.Menu
        RPTprofilesMenu                matlab.ui.container.Menu
        FullTestprofilesMenu           matlab.ui.container.Menu
        TesttimevsVoltageMenu          matlab.ui.container.Menu
        TesttimevsCurrentMenu          matlab.ui.container.Menu
        TesttimevsCapacityMenu         matlab.ui.container.Menu
        TesttimevsTemperatureMenu      matlab.ui.container.Menu
        TesttimevsCrateMenu            matlab.ui.container.Menu
        TesttimevsResistanceMenu       matlab.ui.container.Menu
        SpecificStepNumberprofileMenu  matlab.ui.container.Menu
        CapacityvsVoltageMenu          matlab.ui.container.Menu
        StepTimevsCapacityMenu         matlab.ui.container.Menu
        SteptimevsCurrentMenu          matlab.ui.container.Menu
        StepTimevsVoltageMenu          matlab.ui.container.Menu
        SteptimevsTemperatureMenu      matlab.ui.container.Menu
        CapacityBarChartMenu           matlab.ui.container.Menu
        CellclusterplotMenu            matlab.ui.container.Menu
        DVAMenu_2                      matlab.ui.container.Menu
        DischargesequenceMenu          matlab.ui.container.Menu
        ChargesequenceMenu             matlab.ui.container.Menu
        BarGraphMenu                   matlab.ui.container.Menu
        RelativeCapacityMenu           matlab.ui.container.Menu
        RelativeResistanceMenu         matlab.ui.container.Menu
        RelativePowerMenu              matlab.ui.container.Menu
        ErrorplotsMenu                 matlab.ui.container.Menu
        Panel                          matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        Panel_2                        matlab.ui.container.Panel
        PlotButton                     matlab.ui.control.Button
        PlotforfilenumberSpinnerLabel  matlab.ui.control.Label
        PlotforfilenumberSpinner       matlab.ui.control.Spinner
        Label                          matlab.ui.control.Label
        AvailableStepnumbersLabel      matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        PlotformultiplefilesButton     matlab.ui.control.Button
        Panel_3                        matlab.ui.container.Panel
        DVAICAPlotParamtersLabel       matlab.ui.control.Label
        XLimitEditField                matlab.ui.control.EditField
        YLimitEditField                matlab.ui.control.EditField
        LineWidthSpinner               matlab.ui.control.Spinner
        CheckBox                       matlab.ui.control.CheckBox
        ChangeTitleLabel               matlab.ui.control.Label
        XLimitLabel                    matlab.ui.control.Label
        YLimitLabel                    matlab.ui.control.Label
        PlotLineWidthLabel             matlab.ui.control.Label
        ChangeLegendLabelandSize       matlab.ui.control.Label
        CheckBox_2                     matlab.ui.control.CheckBox
        QuickProfileplotsForSpecificStepNumbersPanel  matlab.ui.container.Panel
        CapacityVoltageButton          matlab.ui.control.Button
        SteptimeCurrentButton          matlab.ui.control.Button
        SteptimeVoltageButton          matlab.ui.control.Button
        RunCheckupAnalysisQuickButtonsPanel  matlab.ui.container.Panel
        DischargeCapaictyButton        matlab.ui.control.Button
        ResistanceButton               matlab.ui.control.Button
        DVADischargeSequenceButton     matlab.ui.control.Button
        ContextMenu                    matlab.ui.container.ContextMenu
        OpenPlotinMATLABFigureforeditingMenu  matlab.ui.container.Menu
        SavePlotMenu                   matlab.ui.container.Menu
    end

    
    properties (Access = public)
        Temperature;Voltage;Current;Time;Capacity; cell_name;datas
        Cells       % Container for all cells and their properties
       
        plotVal   % full test time variable type, current, voltage etc
        ax           % temporary axes for subplots
        filename   % individual file name
        lbx % listbox for cell serial numbers and names
        UniqSerial  % unique files serial number for basytec files
        uniqcellname % unique cell names for basytec files
        selected_Cells % index of user selected values
        data_source   % can be from Basytec, Novonix or Posibot Database
        plot_type    % type of plot, stepNumber or full test time
        stepPlot_val  % step number variable type, current, voltage etc
        File_count %  number of files for each cell serial number
        Diff_analysis % type of differential analysis, DVA or ICA
        StepSource % StepNumber for DVA/ICA source, app detected or user detected
        stepChange   % button to change StepNumber for differential analyses
        User_step %user supplied step number for differential analyses
        Xlimit; Ylimit;axisTitle;legenTitle
        DVA_Alightment % right or left
        Align_right  % button for DVA alignment
        UniqueStepNumber; DVA_legend_Title;DVA_plot_legend_size;
        DVA_plot_font_size; DVA_plot_line_width;DVA_plot_Title;
        getfiles; % store files selected by user
        uidrp; uilbl% additional dropmenu and label for displaying stepNumbers
        remove_limits;% plot DVAs without axes limits
        Test_type; % type of test data, check-up or cycle data
        remove_limits_state;plot_type_retention;
        Nov_status;Arbin_status;
        Cell_cluster_plot;files ; Barplot; fileOption_status;
        retention_type; % either relative capacity or relative resistance
    end
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%% END %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
    
    methods (Access = public)
        
        function  func(app)
            % This function controls visibility state of the panel and buttons
            app.Panel.Visible = 'off';
            app.Panel_2.Visible = 'on';
            app.stepChange.Visible= 'off';
            app.Align_right.Visible ='off';
            app.remove_limits.Visible ='off';
            app.uilbl.Visible ='off';
            app.uidrp.Visible ='off';
            app.Barplot.Visible= 'off';
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%% END %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        function plotDiferentials(app)
            %This function plots DVA and ICA
            app.Barplot.Visible= 'off';
            app.StepSource;
            app.Diff_analysis;
            app.stepChange.Visible= 'off';
            app.Align_right.Visible ='off';
            app.remove_limits.Visible ='off';
            app.Panel.Visible ='off';
            cla(app.UIAxes,'reset')
            app.Panel_2.Visible ='off';
            app.UIAxes.Visible ='off';
            app.uilbl.Visible ='off';
            app.uidrp.Visible ='off';
            app.Panel.Title = 'Diferential plots';
          
           [stepNum,posit,txt_state]= dva_plot(app);  
            
           if stepNum > 9
            stepNum= num2str(stepNum);
           else
           stepNum= num2str(stepNum);
           stepNum = char([' ' stepNum]);
           end
           
            if ~strcmp(app.StepSource, 'user')
                msg2 = ['was used for', ' ',  txt_state];
                txt =['StepNumber', ' ', stepNum,' ', msg2];
                uialert(app.BatteryAnalyticUIFigure,[txt,' ','To use a different Step number, press Change StepNumber button'],"StepNumber","Icon",'warning')
            end
            
            if ~strcmp(app.DVA_Alightment, 'right')
                msg2 = ['DVA was aligned left,',' ', 'to align right, click on Align right button'];
                uialert(app.BatteryAnalyticUIFigure,msg2,"Align DVA","Icon",'warning')
            end
            app.stepChange=uibutton('Parent', app.BatteryAnalyticUIFigure, "Position", [posit-20 30 120 40], 'Text','Change StepNumber');
            app.stepChange.ButtonPushedFcn = @app.Change_StepNumber;
            app.Align_right=uibutton('Parent', app.BatteryAnalyticUIFigure, "Position", [posit+120 30 120 40], 'Text','Align right');
            app.Align_right.ButtonPushedFcn = @app.AlignDVA;
            numbas = cellstr(num2str(app.UniqueStepNumber));
            app.uidrp=uidropdown(app.BatteryAnalyticUIFigure,'Items',numbas,'Position',[posit+375 45 100 25]);
            app.uilbl=uilabel(app.BatteryAnalyticUIFigure,'Text','Current StepNumber','Position',[posit+370 20 120 20]);
        
            app.uidrp.Value = stepNum;
            app.remove_limits=uibutton('Parent', app.BatteryAnalyticUIFigure, "Position", [posit+260 30 90 40],'Text','Remove limits');
            app.remove_limits.ButtonPushedFcn = @app.RemoveDVALimits;
            
        end % end plotDifferentials
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%55 END %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%55
        function plot_retention(app) 
            switch app.plot_type_retention
                
                case 'Line'
            switch app.retention_type
                case 'Capacity'
                    relative_capacity_plot(app)
                case 'Resistance'
                   resistance_plot(app)
                case 'Power'
                   Power_plot(app)
            end
            app.Barplot=uibutton('Parent', app.BatteryAnalyticUIFigure, "Position", [560 30 180 30], 'Text','Plot as bar graph');
            app.Barplot.ButtonPushedFcn = @app.Bargraphs;
            
                case 'Bar'
             switch app.retention_type
                 case {'Capacity','Power'}
                    relative_capacity_barChart(app)
                case 'Resistance'
                   relative_resistance_barChart(app)
             
             end
            
            end
       
        end
        
         
    end
    
    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% END %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
    
    methods (Access = private)
        
        function Change_StepNumber(app,src,event)
            %Function for user supplied stepnumber for DVA and ICA
            %app.stepChange.Visible= 'off';
            state= app.Diff_analysis;
            app.DVA_Alightment;
            switch state
                case 'DVA'
                    txt_state= 'DVA.';
                case 'ICA'
                    txt_state= 'ICA.';
            end
            app.StepSource = 'user';
            userStep = inputdlg(['Enter step number for',' ', txt_state,':'],...
                txt_state, [1 50]);
            if isempty(userStep)
                return
            end
            app.User_step = str2num(userStep{1});
            plotDiferentials(app)
        end
        
        function AlignDVA(app,src,even)
            app.StepSource;
            app.Diff_analysis;
            app.DVA_Alightment = 'right';
            plotDiferentials(app)
        end
        
        function RemoveDVALimits(app,src,even)
            app.remove_limits_state = 'true';
            app.StepSource;
            app.Diff_analysis;
            app.Xlimit = [];
            app.Ylimit=[];
            plotDiferentials(app)
        end
        
        function Bargraphs(app,src,even)
            app.plot_type_retention = 'Bar';
            app.Cell_cluster_plot= 'False';
            plot_retention(app) 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, startup)
           start_up       
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
                      
            selected_cell(app);
            cells = app.selected_Cells;
            app.files   = app.PlotforfilenumberSpinner.Value;
            
            filecount = app.File_count(cells);
            filecount = str2double(filecount);
            
            if app.files > min(filecount)
                app.files =min(filecount);   % ensure that user entered file number is within filecount range
            end
     
            app.fileOption_status=0;
            numCells = size(cells,1);
                     
            switch app.plot_type
                
                case 'full_test_time_plot'
                    
                    Full_test_profile_plots(app,cells,numCells)
                    
                case 'StepNumber plot'
                                        
                    StepPlots(app,cells,numCells)
                                      
            end % end switch plot_type
                       
        end

        % Menu selected function: OpenfilesMenu
        function OpenfilesMenuSelected(app, event)
          
            
        end

        % Menu selected function: TesttimevsVoltageMenu
        function TesttimevsVoltageMenuSelected(app, event)
            
            
            app.plot_type = 'full_test_time_plot';
            app.plotVal = 1;
              app.Cell_cluster_plot = 'False';
             stat= single_data_file_plot(app);
            if stat == 0
                app.Label.Text =' Plot Test time vs Voltage: Select file number and press plot';
            end
        end

        % Menu selected function: TesttimevsCurrentMenu
        function TesttimevsCurrentMenuSelected(app, event)
 
            app.plot_type = 'full_test_time_plot';
            app.plotVal = 2;
              app.Cell_cluster_plot = 'False';
            stat= single_data_file_plot(app);
            if stat == 0
                app.Label.Text =' Plot Test time vs Current: Select file number and press plot';
            end

            
        end

        % Menu selected function: TesttimevsCapacityMenu
        function TesttimevsCapacityMenuSelected(app, event)
         
            app.plot_type = 'full_test_time_plot';
            app.plotVal =3;
              app.Cell_cluster_plot = 'False';
            stat= single_data_file_plot(app);
            if stat == 0
                app.Label.Text =' Plot Test time vs Capacity: Select file number and press plot';
            end
           
        end

        % Menu selected function: TesttimevsTemperatureMenu
        function TesttimevsTemperatureMenuSelected(app, event)

            app.plot_type = 'full_test_time_plot';
            app.plotVal = 4;
              app.Cell_cluster_plot = 'False';
             stat= single_data_file_plot(app);
            if stat == 0
                app.Label.Text =' Plot Test time vs Temperature: Select file number and press plot';
            end
        end

        % Menu selected function: SpecificStepNumberprofileMenu
        function SpecificStepNumberprofileMenuSelected(app, event)


        end

        % Callback function: CapacityVoltageButton, 
        % CapacityvsVoltageMenu
        function CapacityvsVoltageMenuSelected(app, event)

            app.plot_type =  'StepNumber plot';
            app.stepPlot_val = 1;
              app.Cell_cluster_plot = 'False';
            stat= single_data_file_plot(app);
            if stat == 0
                app.Label.Text =' Plot Capacity vs Voltage: Select file number and press plot';
            end
            
           
        end

        % Menu selected function: StepTimevsCapacityMenu
        function StepTimevsCapacityMenuSelected(app, event)

            app.plot_type =  'StepNumber plot';
            app.stepPlot_val = 2;
              app.Cell_cluster_plot = 'False';
            stat= single_data_file_plot(app);
            if stat == 0
                app.Label.Text =' Plot Step time vs Capacity: Select file number and press plot';
            end
        end

        % Callback function: StepTimevsVoltageMenu, 
        % SteptimeVoltageButton
        function StepTimevsVoltageMenuSelected(app, event)

              app.plot_type =  'StepNumber plot';
              app.stepPlot_val = 3;
                app.Cell_cluster_plot = 'False';
              stat= single_data_file_plot(app);
            if stat == 0
                app.Label.Text =' Plot Step time vs Voltage: Select file number and press plot';
            end
        end

        % Callback function: SteptimeCurrentButton, 
        % SteptimevsCurrentMenu
        function SteptimevsCurrentMenuSelected(app, event)

            app.plot_type =  'StepNumber plot';
            app.stepPlot_val = 4;
              app.Cell_cluster_plot = 'False';
           stat= single_data_file_plot(app);
            if stat == 0
                app.Label.Text =' Plot Step time vs Current: Select file number and press plot';
            end
            
        end

        % Menu selected function: SteptimevsTemperatureMenu
        function SteptimevsTemperatureMenuSelected(app, event)
            app.plot_type =  'StepNumber plot';
            app.stepPlot_val = 5;
            app.Cell_cluster_plot = 'False';
           stat= single_data_file_plot(app);
            if stat == 0
                app.Label.Text =' Plot Step time vs Temperature: Select file number and press plot';
            end
        end

        % Menu selected function: SavedatafileMenu
        function SavedatafileMenuSelected(app, event)
           
            Cell_data = app.Cells;
            uniqSerial =  app.UniqSerial;
            Uniqcellname = app.uniqcellname;
            file_count =  app.File_count;
            Data_source= app.data_source;
            fillename = uiputfile('*.mat', "Save Message" );
            if fillename
                save(fillename, 'Cell_data','uniqSerial','Uniqcellname',...
                    'file_count','Data_source');
            end
        end

        % Menu selected function: BasytecfilesMenu
        function BasytecfilesMenuSelected(app, event)
            app.File_count =[];
            cla(app.UIAxes,'reset')
            app.Panel.Visible = 'off';
            app.Panel_2.Visible = 'off';
            app.Panel_3.Visible = 'off';
            app.QuickProfileplotsForSpecificStepNumbersPanel.Visible = 'off';
            app.RunCheckupAnalysisQuickButtonsPanel.Visible = 'off';
            app.stepChange.Visible= 'off';
            app.Align_right.Visible ='off';
            app.remove_limits.Visible ='off';
            app.uilbl.Visible ='off';
            app.uidrp.Visible ='off';
             app.Barplot.Visible ='off';
            try
                Basytec_files(app)
                detect_DVA_steps(app);
                app.Panel_3.Title='Imported Basytec Files';
                StateHandle(app)
                app.data_source = 'Basytec';
                if size(app.Cells,2)==1 && size(app.Cells(1).data,2)==1
                app.BatteryAnalyticUIFigure.Position = [150,100,800,550];
                app.Panel.Position = [50,90,700,450];
                app.Panel_2.Position = [50,15,700,70];
                end
            catch
                
            end
            
        end

        % Menu selected function: NovonixfilesMenu
        function NovonixfilesMenuSelected(app, event)
            app.File_count =[];
            cla(app.UIAxes,'reset')
            app.Panel.Visible = 'off';
            app.Panel_2.Visible = 'off';
            app.Panel_3.Visible = 'off';
            app.QuickProfileplotsForSpecificStepNumbersPanel.Visible = 'off';
            app.RunCheckupAnalysisQuickButtonsPanel.Visible = 'off';
            app.stepChange.Visible= 'off';
            app.Align_right.Visible ='off';
            app.remove_limits.Visible ='off';
            app.uilbl.Visible ='off';
            app.uidrp.Visible ='off';
            app.Barplot.Visible ='off';
            
            Novonix_files(app)
            if app.Nov_status==1
                detect_DVA_steps(app);  % Detect possible step numbers for DVA, user can change if not true
                app.Panel_3.Title='Imported Novonix Files';
                app.data_source = 'Novonix';
                StateHandle(app)
                
                if size(app.Cells,2)==1 && size(app.Cells(1).data,2)==1
                app.BatteryAnalyticUIFigure.Position = [150,100,800,550];
                app.Panel.Position = [50,90,700,450];
                app.Panel_2.Position = [50,15,700,70];
                end
                uialert(app.BatteryAnalyticUIFigure,"Files loaded","Import files","Icon",'success')
            else
                return
            end
        end

        % Value changed function: PlotforfilenumberSpinner
        function PlotforfilenumberSpinnerValueChanged(app, event)
            app.files =[];
            app.files = app.PlotforfilenumberSpinner.Value;


        end

        % Menu selected function: DVAMenu
        function DVAMenuSelected(app, event)


        end

        % Callback function: DVADischargeSequenceButton, 
        % UsingDischargeSequenceMenu
        function UsingDischargeSequenceMenuSelected(app, event)
            
            app.StepSource = 'App_detected_Discharge';
            app.Diff_analysis =  'DVA';
            app.DVA_Alightment = 'left';
              app.Cell_cluster_plot = 'False';
            plotDiferentials(app)


        end

        % Menu selected function: UsingChargeSequenceMenu
        function UsingChargeSequenceMenuSelected(app, event)
            
            app.StepSource = 'App_detected_Charge';
            app.Diff_analysis =  'DVA';
            app.DVA_Alightment = 'left';
              app.Cell_cluster_plot = 'False';
            plotDiferentials(app)
            
        end

        % Menu selected function: UsingDischargeSequenceMenu_2
        function UsingDischargeSequenceMenu_2Selected(app, event)
            
            app.StepSource = 'App_detected_Discharge';
            app.Diff_analysis =  'ICA';
            app.DVA_Alightment = 'left';
              app.Cell_cluster_plot = 'False';
            plotDiferentials(app)
            
            
        end

        % Menu selected function: UsingChargeSequenceMenu_2
        function UsingChargeSequenceMenu_2Selected(app, event)
            
            app.StepSource = 'App_detected_Charge';
            app.Diff_analysis =  'ICA';
            app.DVA_Alightment = 'left';
              app.Cell_cluster_plot = 'False';
            plotDiferentials(app)

        end

        % Callback function: DischargeCapacityMenu, 
        % DischargeCapaictyButton
        function DischargeCapacityMenuSelected(app, event)
            % Check-up discharge capacity callback
            % Ask user to put in the cycle length: 50, 100 etc
            app.Test_type = 'Check-Up';
            app.retention_type = 'Capacity';
            app.plot_type_retention = 'Line';
            plot_retention(app)
            
            
        end

        % Callback function: ResistanceButton, ResistanceMenu
        function ResistanceMenuSelected(app, event)
            % Check-up discharge Resistance callback
            % Ask user to put in the cycle length: 50, 100 etc
            app.Test_type = 'Check-Up';
            app.plot_type_retention = 'Line';
            app.retention_type = 'Resistance';
            plot_retention(app)
          
        end

        % Menu selected function: DischargeCapacityMenu_2
        function DischargeCapacityMenu_2Selected(app, event)
            % Capacity retention from cycling data
            app.Test_type = 'Cycle-Continous';
            app.plot_type_retention = 'Line';
            app.retention_type = 'Capacity';
            plot_retention(app)
        end

        % Menu selected function: ResistanceMenu_2
        function ResistanceMenu_2Selected(app, event)
            % Resistance retention from cycling data
             app.Test_type = 'Cycle-Continous';
             app.plot_type_retention = 'Line';
             app.retention_type = 'Resistance';
            plot_retention(app)
        end

        % Menu selected function: DischargecapacityMenu
        function DischargecapacityMenuSelected(app, event)
            % Full equivalent cycles discharge capacity callback
            % Ask user to put in the cycle length: 50, 100 etc
            app.Test_type='Cycle-FEC';
            app.plot_type_retention = 'Line';
            app.retention_type = 'Capacity';
            plot_retention(app)
        end

        % Menu selected function: ResistanceMenu_3
        function ResistanceMenu_3Selected(app, event)
            % Full equivalent cycles resistance callback
            % Ask user to put in the cycle length: 50, 100 etc
            app.Test_type= 'Cycle-FEC';
            app.plot_type_retention = 'Line';
            app.retention_type = 'Resistance';
            plot_retention(app)
        end

        % Menu selected function: LoaddatafileMenu
        function LoaddatafileMenuSelected(app, event)
          load_data(app)
        end

        % Menu selected function: SavePlotMenu, SaveplotMenu
        function SaveplotMenuSelected(app, event)
                save_plot(app) 
            
        end

        % Menu selected function: 
        % OpenPlotinMATLABFigureforeditingMenu, 
        % OpenplotinMATLABfigureMenu
        function OpenPlotinMATLABFigureforeditingMenuSelected(app, event)
           open_plot(app)
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
getTitle_and_size = {'Enter Plot Title:','Enter Font Size:'};
dlgtitle = 'Title';
dims = [1 50];
definput = {'DVA Plots','5',};
answer = inputdlg(getTitle_and_size,dlgtitle,dims,definput);
app.DVA_plot_Title = answer{1};
app.DVA_plot_font_size = answer{2};
            
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
getTitle_and_size = {'Enter Legend label:','Enter Font Size:'};
dlgtitle = 'Legend';
dims = [1 50];
definput = {'DVA Plots','3',};
answer = inputdlg(getTitle_and_size,dlgtitle,dims,definput);
app.DVA_legend_Title = answer{1};
app.DVA_plot_legend_size= answer{2};
            
        end

        % Value changed function: LineWidthSpinner
        function LineWidthSpinnerValueChanged(app, event)
            value = app.LineWidthSpinner.Value;
            app.DVA_plot_line_width = value;
        end

        % Value changed function: XLimitEditField
        function XLimitEditFieldValueChanged(app, event)
            value = app.XLimitEditField.Value;
            value= convertCharsToStrings(value);
            value = str2num(value{1});
            app.Xlimit(1) = min(value);
            app.Xlimit(2) = max(value);
        end

        % Value changed function: YLimitEditField
        function YLimitEditFieldValueChanged(app, event)
            value = app.YLimitEditField.Value;
            value= convertCharsToStrings(value);
            value = str2num(value{1});
            app.Ylimit(1) = min(value);
            app.Ylimit(2) = max(value);
            
        end

        % Menu selected function: TesttimevsCrateMenu
        function TesttimevsCrateMenuSelected(app, event)
            
            app.plot_type = 'full_test_time_plot';
            app.plotVal = 5;
            app.Cell_cluster_plot = 'False';
            stat= single_data_file_plot(app);
            if stat == 0
                need to resolve rated capacity values
                app.Label.Text =' Plot Test time vs C-rate: Select file number and press plot';
            end
            
        end

        % Menu selected function: CapacityBarChartMenu
        function CapacityBarChartMenuSelected(app, event)
           app.plot_type =  'StepNumber plot';
            app.stepPlot_val = 6;
            app.Cell_cluster_plot = 'False';
           stat= single_data_file_plot(app);
            if stat == 0
                app.Label.Text =' Plot Capacity Bar Graph: Select file number and press plot';
            end
        end

        % Button pushed function: PlotformultiplefilesButton
        function PlotformultiplefilesButtonPushed(app, event)
            numfiles =str2double(app.File_count);
            selected_cell(app);
            cells = app.selected_Cells;
            numCells = size(cells,1);
            mesg= 'Enter File numbers to plot';
           file_selection_handle(app,cells,numfiles,mesg)
            
            switch app.plot_type
                case 'full_test_time_plot'
                    Full_test_profile_plots(app,cells,numCells)
                    
                case 'StepNumber plot'
                       
                    StepPlots(app,cells,numCells)
            end
            
        end

        % Menu selected function: DischargesequenceMenu
        function DischargesequenceMenuSelected(app, event)
            app.Cell_cluster_plot = 'True';
            app.StepSource = 'App_detected_Discharge';
            app.Diff_analysis =  'DVA';
            app.DVA_Alightment = 'left';
            plotDiferentials(app)
        end

        % Menu selected function: TesttimevsResistanceMenu
        function TesttimevsResistanceMenuSelected(app, event)
            app.plot_type = 'full_test_time_plot';
            app.plotVal = 6;
              app.Cell_cluster_plot = 'False';
             stat= single_data_file_plot(app);
            if stat == 0
                app.Label.Text =' Plot Test time vs Resistance: Select file number and press plot';
            end
        end

        % Menu selected function: ChargesequenceMenu
        function ChargesequenceMenuSelected(app, event)
            app.Cell_cluster_plot = 'True';
            app.StepSource = 'App_detected_Charge';
            app.Diff_analysis =  'DVA';
            app.DVA_Alightment = 'left';
            plotDiferentials(app)
        end

        % Menu selected function: RelativeCapacityMenu
        function RelativeCapacityMenuSelected(app, event)
           % Handle for relative capacity fade bar chart plot
            % app.Panel_2.Visible= 'off';
%             val = {'Check-Up data', 'Cycle data','Full Equiv Cycle'};
%              app.uilbl=uilabel(app.BatteryAnalyticUIFigure,'Text','Select test type/plot option ','Position',[550 20 200 20]);
%              app.uidrp=uidropdown(app.BatteryAnalyticUIFigure,'Items',val,'Position',[560 45 150 25]);
            
            app.Test_type = 'Check-Up';
            app.Cell_cluster_plot = 'True';
            app.retention_type = 'Capacity';
            relative_capacity_barChart(app)
             
            
        end

        % Menu selected function: PowerMenu
        function PowerMenuSelected(app, event)
           % Handle for relative power fade bar chart plot
            app.Test_type = 'Check-Up';
            app.plot_type_retention = 'Line';
            app.retention_type = 'Power';
            plot_retention(app)
        end

        % Menu selected function: RelativeResistanceMenu
        function RelativeResistanceMenuSelected(app, event)
             % Handle for relative resistance bar chart plot
            app.Test_type = 'Check-Up';
            app.Cell_cluster_plot = 'True';
            app.retention_type = 'Resistance';
            relative_resistance_barChart(app)
             
        end

        % Menu selected function: RelativePowerMenu
        function RelativePowerMenuSelected(app, event)
            app.Test_type = 'Check-Up';
            app.Cell_cluster_plot = 'True';
            app.retention_type = 'Power';
            relative_capacity_barChart(app)
        end

        % Menu selected function: SOCvsCapacityMenu
        function SOCvsCapacityMenuSelected(app, event)
            app.Test_type = 'Check-Up';
            app.retention_type = 'Capacity';
            app.plot_type_retention = 'Line';
           %
            %relative_capacity_SOC_plot(app)
            relative_resistance_SOC_plot(app)
            %plot_retention(app) 
        end

        % Menu selected function: ArbinfilesMenu
        function ArbinfilesMenuSelected(app, event)
            app.File_count =[];
            cla(app.UIAxes,'reset')
            app.Panel.Visible = 'off';
            app.Panel_2.Visible = 'off';
            app.Panel_3.Visible = 'off';
            app.QuickProfileplotsForSpecificStepNumbersPanel.Visible = 'off';
            app.RunCheckupAnalysisQuickButtonsPanel.Visible = 'off';
            app.stepChange.Visible= 'off';
            app.Align_right.Visible ='off';
            app.remove_limits.Visible ='off';
            app.uilbl.Visible ='off';
            app.uidrp.Visible ='off';
            app.Barplot.Visible ='off';
            
            Arbin_files(app)
            if app.Arbin_status ==1
                app.Panel_3.Title='Imported Arbin Cycler Files';
                app.data_source = 'Arbin';
                StateHandle(app)
                uialert(app.BatteryAnalyticUIFigure,"Files loaded","Import files","Icon",'success')
            else
                return
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BatteryAnalyticUIFigure = uifigure('Visible', 'off');
            app.BatteryAnalyticUIFigure.Name = 'Battery Analytic';
            app.BatteryAnalyticUIFigure.Tag = 'Battery Analytic';
            app.FileMenu = uimenu(app.BatteryAnalyticUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenfilesMenu = uimenu(app.FileMenu);
            app.OpenfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenfilesMenuSelected, true);
            app.OpenfilesMenu.Text = 'Open files';
            app.BasytecfilesMenu = uimenu(app.OpenfilesMenu);
            app.BasytecfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @BasytecfilesMenuSelected, true);
            app.BasytecfilesMenu.Text = 'Basytec files';
            app.NovonixfilesMenu = uimenu(app.OpenfilesMenu);
            app.NovonixfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @NovonixfilesMenuSelected, true);
            app.NovonixfilesMenu.Text = 'Novonix files';
            app.ArbinfilesMenu = uimenu(app.OpenfilesMenu);
            app.ArbinfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @ArbinfilesMenuSelected, true);
            app.ArbinfilesMenu.Text = 'Arbin files';
            app.DatabasefilesMenu = uimenu(app.OpenfilesMenu);
            app.DatabasefilesMenu.Text = 'Database files';
            app.LoaddatafileMenu = uimenu(app.FileMenu);
            app.LoaddatafileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoaddatafileMenuSelected, true);
            app.LoaddatafileMenu.Text = 'Load data file';
            app.SavedatafileMenu = uimenu(app.FileMenu);
            app.SavedatafileMenu.MenuSelectedFcn = createCallbackFcn(app, @SavedatafileMenuSelected, true);
            app.SavedatafileMenu.Text = 'Save data file';
            app.SaveplotMenu = uimenu(app.FileMenu);
            app.SaveplotMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveplotMenuSelected, true);
            app.SaveplotMenu.Text = 'Save plot';
            app.OpenplotinMATLABfigureMenu = uimenu(app.FileMenu);
            app.OpenplotinMATLABfigureMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenPlotinMATLABFigureforeditingMenuSelected, true);
            app.OpenplotinMATLABfigureMenu.Text = 'Open plot in MATLAB figure';
            app.AnalysisMenu = uimenu(app.BatteryAnalyticUIFigure);
            app.AnalysisMenu.Text = 'Analysis';
            app.DVAMenu = uimenu(app.AnalysisMenu);
            app.DVAMenu.MenuSelectedFcn = createCallbackFcn(app, @DVAMenuSelected, true);
            app.DVAMenu.Text = 'DVA';
            app.UsingDischargeSequenceMenu = uimenu(app.DVAMenu);
            app.UsingDischargeSequenceMenu.MenuSelectedFcn = createCallbackFcn(app, @UsingDischargeSequenceMenuSelected, true);
            app.UsingDischargeSequenceMenu.Text = 'Using Discharge Sequence';
            app.UsingChargeSequenceMenu = uimenu(app.DVAMenu);
            app.UsingChargeSequenceMenu.MenuSelectedFcn = createCallbackFcn(app, @UsingChargeSequenceMenuSelected, true);
            app.UsingChargeSequenceMenu.Text = 'Using Charge Sequence';
            app.CapacityretentionMenu = uimenu(app.AnalysisMenu);
            app.CapacityretentionMenu.Text = 'Capacity retention';
            app.FromCheckupdataMenu = uimenu(app.CapacityretentionMenu);
            app.FromCheckupdataMenu.Text = 'From Check-up data';
            app.DischargeCapacityMenu = uimenu(app.FromCheckupdataMenu);
            app.DischargeCapacityMenu.MenuSelectedFcn = createCallbackFcn(app, @DischargeCapacityMenuSelected, true);
            app.DischargeCapacityMenu.Text = 'Discharge Capacity';
            app.ResistanceMenu = uimenu(app.FromCheckupdataMenu);
            app.ResistanceMenu.MenuSelectedFcn = createCallbackFcn(app, @ResistanceMenuSelected, true);
            app.ResistanceMenu.Text = 'Resistance';
            app.PowerMenu = uimenu(app.FromCheckupdataMenu);
            app.PowerMenu.MenuSelectedFcn = createCallbackFcn(app, @PowerMenuSelected, true);
            app.PowerMenu.Text = 'Power';
            app.SOCvsCapacityMenu = uimenu(app.FromCheckupdataMenu);
            app.SOCvsCapacityMenu.MenuSelectedFcn = createCallbackFcn(app, @SOCvsCapacityMenuSelected, true);
            app.SOCvsCapacityMenu.Text = 'SOC vs Capacity';
            app.FromCycledataMenu = uimenu(app.CapacityretentionMenu);
            app.FromCycledataMenu.Text = 'From Cycle data';
            app.ContinousMenu = uimenu(app.FromCycledataMenu);
            app.ContinousMenu.Text = 'Continous ';
            app.DischargeCapacityMenu_2 = uimenu(app.ContinousMenu);
            app.DischargeCapacityMenu_2.MenuSelectedFcn = createCallbackFcn(app, @DischargeCapacityMenu_2Selected, true);
            app.DischargeCapacityMenu_2.Text = 'Discharge Capacity';
            app.ResistanceMenu_2 = uimenu(app.ContinousMenu);
            app.ResistanceMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ResistanceMenu_2Selected, true);
            app.ResistanceMenu_2.Text = 'Resistance';
            app.PowerMenu_2 = uimenu(app.ContinousMenu);
            app.PowerMenu_2.Text = 'Power';
            app.FECMenu = uimenu(app.FromCycledataMenu);
            app.FECMenu.Text = 'FEC';
            app.DischargecapacityMenu = uimenu(app.FECMenu);
            app.DischargecapacityMenu.MenuSelectedFcn = createCallbackFcn(app, @DischargecapacityMenuSelected, true);
            app.DischargecapacityMenu.Text = 'Discharge capacity';
            app.ResistanceMenu_3 = uimenu(app.FECMenu);
            app.ResistanceMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ResistanceMenu_3Selected, true);
            app.ResistanceMenu_3.Text = 'Resistance';
            app.PowerMenu_3 = uimenu(app.FECMenu);
            app.PowerMenu_3.Text = 'Power';
            app.ICAMenu = uimenu(app.AnalysisMenu);
            app.ICAMenu.Text = 'ICA';
            app.UsingDischargeSequenceMenu_2 = uimenu(app.ICAMenu);
            app.UsingDischargeSequenceMenu_2.MenuSelectedFcn = createCallbackFcn(app, @UsingDischargeSequenceMenu_2Selected, true);
            app.UsingDischargeSequenceMenu_2.Text = 'Using Discharge Sequence';
            app.UsingChargeSequenceMenu_2 = uimenu(app.ICAMenu);
            app.UsingChargeSequenceMenu_2.MenuSelectedFcn = createCallbackFcn(app, @UsingChargeSequenceMenu_2Selected, true);
            app.UsingChargeSequenceMenu_2.Text = 'Using Charge Sequence';
            app.PlotsMenu_2 = uimenu(app.BatteryAnalyticUIFigure);
            app.PlotsMenu_2.Text = 'Plots';
            app.PlotsMenu = uimenu(app.PlotsMenu_2);
            app.PlotsMenu.Text = 'Plots';
            app.RPTprofilesMenu = uimenu(app.PlotsMenu);
            app.RPTprofilesMenu.Text = 'RPT profiles';
            app.FullTestprofilesMenu = uimenu(app.RPTprofilesMenu);
            app.FullTestprofilesMenu.Text = 'Full Test profiles';
            app.TesttimevsVoltageMenu = uimenu(app.FullTestprofilesMenu);
            app.TesttimevsVoltageMenu.MenuSelectedFcn = createCallbackFcn(app, @TesttimevsVoltageMenuSelected, true);
            app.TesttimevsVoltageMenu.Text = 'Test time vs Voltage';
            app.TesttimevsCurrentMenu = uimenu(app.FullTestprofilesMenu);
            app.TesttimevsCurrentMenu.MenuSelectedFcn = createCallbackFcn(app, @TesttimevsCurrentMenuSelected, true);
            app.TesttimevsCurrentMenu.Text = 'Test time vs Current';
            app.TesttimevsCapacityMenu = uimenu(app.FullTestprofilesMenu);
            app.TesttimevsCapacityMenu.MenuSelectedFcn = createCallbackFcn(app, @TesttimevsCapacityMenuSelected, true);
            app.TesttimevsCapacityMenu.Text = 'Test time vs Capacity';
            app.TesttimevsTemperatureMenu = uimenu(app.FullTestprofilesMenu);
            app.TesttimevsTemperatureMenu.MenuSelectedFcn = createCallbackFcn(app, @TesttimevsTemperatureMenuSelected, true);
            app.TesttimevsTemperatureMenu.Text = 'Test time vs Temperature';
            app.TesttimevsCrateMenu = uimenu(app.FullTestprofilesMenu);
            app.TesttimevsCrateMenu.MenuSelectedFcn = createCallbackFcn(app, @TesttimevsCrateMenuSelected, true);
            app.TesttimevsCrateMenu.Text = 'Test time vs C-rate';
            app.TesttimevsResistanceMenu = uimenu(app.FullTestprofilesMenu);
            app.TesttimevsResistanceMenu.MenuSelectedFcn = createCallbackFcn(app, @TesttimevsResistanceMenuSelected, true);
            app.TesttimevsResistanceMenu.Text = 'Test time vs Resistance';
            app.SpecificStepNumberprofileMenu = uimenu(app.RPTprofilesMenu);
            app.SpecificStepNumberprofileMenu.MenuSelectedFcn = createCallbackFcn(app, @SpecificStepNumberprofileMenuSelected, true);
            app.SpecificStepNumberprofileMenu.Text = 'Specific StepNumber profile';
            app.CapacityvsVoltageMenu = uimenu(app.SpecificStepNumberprofileMenu);
            app.CapacityvsVoltageMenu.MenuSelectedFcn = createCallbackFcn(app, @CapacityvsVoltageMenuSelected, true);
            app.CapacityvsVoltageMenu.Text = 'Capacity vs Voltage';
            app.StepTimevsCapacityMenu = uimenu(app.SpecificStepNumberprofileMenu);
            app.StepTimevsCapacityMenu.MenuSelectedFcn = createCallbackFcn(app, @StepTimevsCapacityMenuSelected, true);
            app.StepTimevsCapacityMenu.Text = 'Step Time vs Capacity';
            app.SteptimevsCurrentMenu = uimenu(app.SpecificStepNumberprofileMenu);
            app.SteptimevsCurrentMenu.MenuSelectedFcn = createCallbackFcn(app, @SteptimevsCurrentMenuSelected, true);
            app.SteptimevsCurrentMenu.Text = 'Step time vs Current';
            app.StepTimevsVoltageMenu = uimenu(app.SpecificStepNumberprofileMenu);
            app.StepTimevsVoltageMenu.MenuSelectedFcn = createCallbackFcn(app, @StepTimevsVoltageMenuSelected, true);
            app.StepTimevsVoltageMenu.Text = 'Step Time vs  Voltage';
            app.SteptimevsTemperatureMenu = uimenu(app.SpecificStepNumberprofileMenu);
            app.SteptimevsTemperatureMenu.MenuSelectedFcn = createCallbackFcn(app, @SteptimevsTemperatureMenuSelected, true);
            app.SteptimevsTemperatureMenu.Text = 'Step time vs Temperature';
            app.CapacityBarChartMenu = uimenu(app.SpecificStepNumberprofileMenu);
            app.CapacityBarChartMenu.MenuSelectedFcn = createCallbackFcn(app, @CapacityBarChartMenuSelected, true);
            app.CapacityBarChartMenu.Text = 'Capacity Bar Chart';
            app.CellclusterplotMenu = uimenu(app.PlotsMenu_2);
            app.CellclusterplotMenu.Text = 'Cell-cluster-plot';
            app.DVAMenu_2 = uimenu(app.CellclusterplotMenu);
            app.DVAMenu_2.Text = 'DVA';
            app.DischargesequenceMenu = uimenu(app.DVAMenu_2);
            app.DischargesequenceMenu.MenuSelectedFcn = createCallbackFcn(app, @DischargesequenceMenuSelected, true);
            app.DischargesequenceMenu.Text = 'Discharge sequence';
            app.ChargesequenceMenu = uimenu(app.DVAMenu_2);
            app.ChargesequenceMenu.MenuSelectedFcn = createCallbackFcn(app, @ChargesequenceMenuSelected, true);
            app.ChargesequenceMenu.Text = 'Charge sequence';
            app.BarGraphMenu = uimenu(app.CellclusterplotMenu);
            app.BarGraphMenu.Text = 'Bar Graph';
            app.RelativeCapacityMenu = uimenu(app.BarGraphMenu);
            app.RelativeCapacityMenu.MenuSelectedFcn = createCallbackFcn(app, @RelativeCapacityMenuSelected, true);
            app.RelativeCapacityMenu.Text = 'Relative Capacity';
            app.RelativeResistanceMenu = uimenu(app.BarGraphMenu);
            app.RelativeResistanceMenu.MenuSelectedFcn = createCallbackFcn(app, @RelativeResistanceMenuSelected, true);
            app.RelativeResistanceMenu.Text = 'Relative Resistance';
            app.RelativePowerMenu = uimenu(app.BarGraphMenu);
            app.RelativePowerMenu.MenuSelectedFcn = createCallbackFcn(app, @RelativePowerMenuSelected, true);
            app.RelativePowerMenu.Text = 'Relative Power';
            app.ErrorplotsMenu = uimenu(app.CellclusterplotMenu);
            app.ErrorplotsMenu.Text = 'Error plots';
            app.Panel = uipanel(app.BatteryAnalyticUIFigure);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Panel_2 = uipanel(app.BatteryAnalyticUIFigure);
            app.PlotButton = uibutton(app.Panel_2, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot ';
            app.PlotforfilenumberSpinnerLabel = uilabel(app.Panel_2);
            app.PlotforfilenumberSpinnerLabel.Text = 'Plot  for file number';
            app.PlotforfilenumberSpinner = uispinner(app.Panel_2);
            app.PlotforfilenumberSpinner.Limits = [1 Inf];
            app.PlotforfilenumberSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotforfilenumberSpinnerValueChanged, true);
            app.PlotforfilenumberSpinner.Value = 1;
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '';
            app.AvailableStepnumbersLabel = uilabel(app.Panel_2);
            app.AvailableStepnumbersLabel.Text = 'Available Stepnumbers';
            app.DropDown = uidropdown(app.Panel_2);
            app.DropDown.Items = {};
            app.DropDown.Value = {};
            app.PlotformultiplefilesButton = uibutton(app.Panel_2, 'push');
            app.PlotformultiplefilesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotformultiplefilesButtonPushed, true);
            app.PlotformultiplefilesButton.Text = 'Plot  for multiple files';
            app.Panel_3 = uipanel(app.BatteryAnalyticUIFigure);
            app.Panel_3.Title = 'Panel';
            app.DVAICAPlotParamtersLabel = uilabel(app.Panel_3);
            app.DVAICAPlotParamtersLabel.Text = 'DVA / ICA Plot Paramters';
            app.XLimitEditField = uieditfield(app.Panel_3, 'text');
            app.XLimitEditField.ValueChangedFcn = createCallbackFcn(app, @XLimitEditFieldValueChanged, true);
            app.XLimitEditField.Value = '0.0 , 3.0';
            app.YLimitEditField = uieditfield(app.Panel_3, 'text');
            app.YLimitEditField.ValueChangedFcn = createCallbackFcn(app, @YLimitEditFieldValueChanged, true);
            app.YLimitEditField.Value = '0.0 , 1.5';
            app.LineWidthSpinner = uispinner(app.Panel_3);
            app.LineWidthSpinner.Step = 0.1;
            app.LineWidthSpinner.Limits = [0 10];
            app.LineWidthSpinner.ValueChangedFcn = createCallbackFcn(app, @LineWidthSpinnerValueChanged, true);
            app.LineWidthSpinner.Value = 0.5;
            app.CheckBox = uicheckbox(app.Panel_3);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.ChangeTitleLabel = uilabel(app.Panel_3);
            app.ChangeTitleLabel.Text = 'Change Title';
            app.XLimitLabel = uilabel(app.Panel_3);
            app.XLimitLabel.Text = 'X-Limit';
            app.YLimitLabel = uilabel(app.Panel_3);
            app.YLimitLabel.Text = 'Y-Limit';
            app.PlotLineWidthLabel = uilabel(app.Panel_3);
            app.PlotLineWidthLabel.Text = 'Plot Line Width';
            app.ChangeLegendLabelandSize = uilabel(app.Panel_3);
            app.ChangeLegendLabelandSize.Text = ' Change Legend Label and Size';
            app.CheckBox_2 = uicheckbox(app.Panel_3);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.QuickProfileplotsForSpecificStepNumbersPanel = uipanel(app.BatteryAnalyticUIFigure);
            app.QuickProfileplotsForSpecificStepNumbersPanel.Title = ' Quick Profile plots For Specific StepNumbers';
            app.CapacityVoltageButton = uibutton(app.QuickProfileplotsForSpecificStepNumbersPanel, 'push');
            app.CapacityVoltageButton.ButtonPushedFcn = createCallbackFcn(app, @CapacityvsVoltageMenuSelected, true);
            app.CapacityVoltageButton.Text = 'Capacity - Voltage';
            app.SteptimeCurrentButton = uibutton(app.QuickProfileplotsForSpecificStepNumbersPanel, 'push');
            app.SteptimeCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @SteptimevsCurrentMenuSelected, true);
            app.SteptimeCurrentButton.Text = 'Step time - Current';
            app.SteptimeVoltageButton = uibutton(app.QuickProfileplotsForSpecificStepNumbersPanel, 'push');
            app.SteptimeVoltageButton.ButtonPushedFcn = createCallbackFcn(app, @StepTimevsVoltageMenuSelected, true);
            app.SteptimeVoltageButton.Text = 'Step time - Voltage';
            app.RunCheckupAnalysisQuickButtonsPanel = uipanel(app.BatteryAnalyticUIFigure);
            app.RunCheckupAnalysisQuickButtonsPanel.Title = 'Run Check-up Analysis: Quick Buttons';
            app.DischargeCapaictyButton = uibutton(app.RunCheckupAnalysisQuickButtonsPanel, 'push');
            app.DischargeCapaictyButton.ButtonPushedFcn = createCallbackFcn(app, @DischargeCapacityMenuSelected, true);
            app.DischargeCapaictyButton.Text = 'Discharge Capaicty';
            app.ResistanceButton = uibutton(app.RunCheckupAnalysisQuickButtonsPanel, 'push');
            app.ResistanceButton.ButtonPushedFcn = createCallbackFcn(app, @ResistanceMenuSelected, true);
            app.ResistanceButton.Text = ' Resistance';
            app.DVADischargeSequenceButton = uibutton(app.RunCheckupAnalysisQuickButtonsPanel, 'push');
            app.DVADischargeSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @UsingDischargeSequenceMenuSelected, true);
            app.DVADischargeSequenceButton.Text = ' DVA Discharge Sequence ';
            app.ContextMenu = uicontextmenu(app.BatteryAnalyticUIFigure);
            % Assign app.ContextMenu
            app.Panel.ContextMenu = app.ContextMenu;
            app.OpenPlotinMATLABFigureforeditingMenu = uimenu(app.ContextMenu);
            app.OpenPlotinMATLABFigureforeditingMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenPlotinMATLABFigureforeditingMenuSelected, true);
            app.OpenPlotinMATLABFigureforeditingMenu.Text = 'Open Plot in MATLAB Figure for editing';
            app.SavePlotMenu = uimenu(app.ContextMenu);
            app.SavePlotMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveplotMenuSelected, true);
            app.SavePlotMenu.Text = 'Save Plot';
            % Show the figure after all components are created
            app.BatteryAnalyticUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BatteryAnalytic(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BatteryAnalyticUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BatteryAnalyticUIFigure)
        end
    end
end